classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CANCERPREDICTIONLabel         matlab.ui.control.Label
        givedataPanel_2               matlab.ui.container.Panel
        a30                           matlab.ui.control.NumericEditField
        fractal_dimension_worstLabel  matlab.ui.control.Label
        a29                           matlab.ui.control.NumericEditField
        symmetry_worstLabel           matlab.ui.control.Label
        a28                           matlab.ui.control.NumericEditField
        concavepoints_worstLabel      matlab.ui.control.Label
        a27                           matlab.ui.control.NumericEditField
        concavity_worstLabel          matlab.ui.control.Label
        a26                           matlab.ui.control.NumericEditField
        compactness_worstLabel        matlab.ui.control.Label
        a25                           matlab.ui.control.NumericEditField
        smoothness_worstLabel         matlab.ui.control.Label
        a24                           matlab.ui.control.NumericEditField
        a24Label                      matlab.ui.control.Label
        a23                           matlab.ui.control.NumericEditField
        a23Label                      matlab.ui.control.Label
        a22                           matlab.ui.control.NumericEditField
        a22Label                      matlab.ui.control.Label
        a21                           matlab.ui.control.NumericEditField
        a21Label                      matlab.ui.control.Label
        givedataPanel                 matlab.ui.container.Panel
        a20                           matlab.ui.control.NumericEditField
        fractal_dimension_seLabel     matlab.ui.control.Label
        a19                           matlab.ui.control.NumericEditField
        symmetry_seLabel              matlab.ui.control.Label
        a18                           matlab.ui.control.NumericEditField
        concavepoints_seLabel         matlab.ui.control.Label
        a17                           matlab.ui.control.NumericEditField
        concavity_seLabel             matlab.ui.control.Label
        a16                           matlab.ui.control.NumericEditField
        compactness_seLabel           matlab.ui.control.Label
        a15                           matlab.ui.control.NumericEditField
        smoothness_seLabel            matlab.ui.control.Label
        a14                           matlab.ui.control.NumericEditField
        area_seLabel                  matlab.ui.control.Label
        a13                           matlab.ui.control.NumericEditField
        perimeter_seLabel             matlab.ui.control.Label
        a12                           matlab.ui.control.NumericEditField
        texture_seLabel               matlab.ui.control.Label
        a11                           matlab.ui.control.NumericEditField
        radius_seLabel                matlab.ui.control.Label
        a10                           matlab.ui.control.NumericEditField
        fractal_dimension_meanLabel   matlab.ui.control.Label
        a9                            matlab.ui.control.NumericEditField
        symmetry_meanLabel            matlab.ui.control.Label
        a8                            matlab.ui.control.NumericEditField
        concavepoints_meanLabel       matlab.ui.control.Label
        a7                            matlab.ui.control.NumericEditField
        concavity_meanLabel           matlab.ui.control.Label
        a6                            matlab.ui.control.NumericEditField
        compactness_meanLabel         matlab.ui.control.Label
        a1                            matlab.ui.control.NumericEditField
        a1Label                       matlab.ui.control.Label
        a5                            matlab.ui.control.NumericEditField
        smoothness_meanLabel          matlab.ui.control.Label
        a4                            matlab.ui.control.NumericEditField
        AreaMeanLabel                 matlab.ui.control.Label
        a3                            matlab.ui.control.NumericEditField
        PerimeterMeanLabel            matlab.ui.control.Label
        a2                            matlab.ui.control.NumericEditField
        swLabel                       matlab.ui.control.Label
        DIAGNOSISPanel                matlab.ui.container.Panel
        ABCLabel                      matlab.ui.control.Label
        TUMORISLabel                  matlab.ui.control.Label
    end

    methods (Access = private)
        
        
        function results = ABC(app)
            %a=load('data4');
            %lk=size(a,2);
            %x=a(568,[3:lk]);
            %x=[1 x];
            x=ones(1,1);
            x=[x app.a1.Value];
            x=[x app.a2.Value];
            x=[x app.a3.Value];
            x=[x app.a4.Value];
            x=[x app.a5.Value];
            x=[x app.a6.Value];
            x=[x app.a7.Value];
            x=[x app.a8.Value];
            x=[x app.a9.Value];
            x=[x app.a10.Value];
            x=[x app.a11.Value];
            x=[x app.a12.Value];
            x=[x app.a13.Value];
            x=[x app.a14.Value];
            x=[x app.a15.Value];
            x=[x app.a16.Value];
            x=[x app.a17.Value];
            x=[x app.a18.Value];
            x=[x app.a19.Value];
            x=[x app.a20.Value];
            x=[x app.a21.Value];
            x=[x app.a22.Value];
            x=[x app.a23.Value];
            x=[x app.a24.Value];
            x=[x app.a25.Value];
            x=[x app.a26.Value];
            x=[x app.a27.Value];
            x=[x app.a28.Value];
            x=[x app.a29.Value];
            x=[x app.a30.Value];
            d=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','processing');
            results=predict(zeros(31,1),x);
            if(results==1)
            app.ABCLabel.Text="BENIGN";
            app.DIAGNOSISPanel.BackgroundColor = [0 1 0];
            else
            app.ABCLabel.Text="MALIGNANT";
            app.DIAGNOSISPanel.BackgroundColor = [1 0 0];
            end
            close(d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: a1
        function a1ValueChanged(app, event)
            value = app.a1.Value;
            ABC(app);
        end

        % Value changed function: a2
        function a2ValueChanged(app, event)
            value = app.a2.Value;
            ABC(app);
        end

        % Value changed function: a3
        function a3ValueChanged(app, event)
            value = app.a3.Value;
            ABC(app);
        end

        % Value changed function: a4
        function a4ValueChanged(app, event)
            value = app.a4.Value;
            ABC(app);
        end

        % Value changed function: a5
        function a5ValueChanged(app, event)
            value = app.a5.Value;
            ABC(app);
        end

        % Value changed function: a6
        function a6ValueChanged(app, event)
            value = app.a6.Value;
            ABC(app);
        end

        % Value changed function: a7
        function a7ValueChanged(app, event)
            value = app.a7.Value;
            ABC(app);
        end

        % Value changed function: a8
        function a8ValueChanged(app, event)
            value = app.a8.Value;
            ABC(app);
        end

        % Value changed function: a9
        function a9ValueChanged(app, event)
            value = app.a9.Value;
            ABC(app);
        end

        % Value changed function: a10
        function a10ValueChanged(app, event)
            value = app.a10.Value;
            ABC(app);
        end

        % Value changed function: a11
        function a11ValueChanged(app, event)
            value = app.a11.Value;
            ABC(app);
        end

        % Value changed function: a12
        function a12ValueChanged(app, event)
            value = app.a12.Value;
            ABC(app);
        end

        % Value changed function: a13
        function a13ValueChanged(app, event)
            value = app.a13.Value;
            ABC(app);
        end

        % Value changed function: a14
        function a14ValueChanged(app, event)
            value = app.a14.Value;
            ABC(app);
        end

        % Value changed function: a15
        function a15ValueChanged(app, event)
            value = app.a15.Value;
            ABC(app);
        end

        % Value changed function: a16
        function a16ValueChanged(app, event)
            value = app.a16.Value;
            ABC(app);
        end

        % Value changed function: a17
        function a17ValueChanged(app, event)
            value = app.a17.Value;
            ABC(app);
        end

        % Value changed function: a18
        function a18ValueChanged(app, event)
            value = app.a18.Value;
            ABC(app);
        end

        % Value changed function: a19
        function a19ValueChanged(app, event)
            value = app.a19.Value;
            ABC(app);
        end

        % Value changed function: a20
        function a20ValueChanged(app, event)
            value = app.a20.Value;
            ABC(app);
        end

        % Value changed function: a21
        function a21ValueChanged(app, event)
            value = app.a21.Value;
            ABC(app);
        end

        % Value changed function: a22
        function a22ValueChanged(app, event)
            value = app.a22.Value;
            ABC(app);
        end

        % Value changed function: a23
        function a23ValueChanged(app, event)
            value = app.a23.Value;
            ABC(app);
        end

        % Value changed function: a24
        function a24ValueChanged(app, event)
            value = app.a24.Value;
            ABC(app);
        end

        % Value changed function: a25
        function a25ValueChanged(app, event)
            value = app.a25.Value;
            ABC(app);
        end

        % Value changed function: a26
        function a26ValueChanged(app, event)
            value = app.a26.Value;
            ABC(app);
        end

        % Value changed function: a27
        function a27ValueChanged(app, event)
            value = app.a27.Value;
            ABC(app);
        end

        % Value changed function: a28
        function a28ValueChanged(app, event)
            value = app.a28.Value;
            ABC(app);
        end

        % Value changed function: a29
        function a29ValueChanged(app, event)
            value = app.a29.Value;
            ABC(app);
        end

        % Value changed function: a30
        function a30ValueChanged(app, event)
            value = app.a30.Value;
            ABC(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DIAGNOSISPanel = uipanel(app.UIFigure);
            app.DIAGNOSISPanel.Title = 'DIAGNOSIS';
            app.TUMORISLabel = uilabel(app.DIAGNOSISPanel);
            app.TUMORISLabel.Text = 'TUMOR IS ';
            app.ABCLabel = uilabel(app.DIAGNOSISPanel);
            app.ABCLabel.Text = {'change any '; 'value to '; 'get result'};
            app.givedataPanel = uipanel(app.UIFigure);
            app.givedataPanel.Title = 'give data';
            app.swLabel = uilabel(app.givedataPanel);
            app.swLabel.Text = 'Texture Mean';
            app.a2 = uieditfield(app.givedataPanel, 'numeric');
            app.a2.ValueChangedFcn = createCallbackFcn(app, @a2ValueChanged, true);
            app.PerimeterMeanLabel = uilabel(app.givedataPanel);
            app.PerimeterMeanLabel.Text = 'Perimeter Mean';
            app.a3 = uieditfield(app.givedataPanel, 'numeric');
            app.a3.ValueChangedFcn = createCallbackFcn(app, @a3ValueChanged, true);
            app.AreaMeanLabel = uilabel(app.givedataPanel);
            app.AreaMeanLabel.Text = 'Area Mean';
            app.a4 = uieditfield(app.givedataPanel, 'numeric');
            app.a4.ValueChangedFcn = createCallbackFcn(app, @a4ValueChanged, true);
            app.smoothness_meanLabel = uilabel(app.givedataPanel);
            app.smoothness_meanLabel.Text = 'smoothness_mean';
            app.a5 = uieditfield(app.givedataPanel, 'numeric');
            app.a5.ValueChangedFcn = createCallbackFcn(app, @a5ValueChanged, true);
            app.a1Label = uilabel(app.givedataPanel);
            app.a1Label.Text = 'Radius Mean';
            app.a1 = uieditfield(app.givedataPanel, 'numeric');
            app.a1.Limits = [0 40];
            app.a1.ValueChangedFcn = createCallbackFcn(app, @a1ValueChanged, true);
            app.a1.Value = 0.1918;
            app.compactness_meanLabel = uilabel(app.givedataPanel);
            app.compactness_meanLabel.Text = 'compactness_mean';
            app.a6 = uieditfield(app.givedataPanel, 'numeric');
            app.a6.ValueChangedFcn = createCallbackFcn(app, @a6ValueChanged, true);
            app.concavity_meanLabel = uilabel(app.givedataPanel);
            app.concavity_meanLabel.Text = 'concavity_mean';
            app.a7 = uieditfield(app.givedataPanel, 'numeric');
            app.a7.ValueChangedFcn = createCallbackFcn(app, @a7ValueChanged, true);
            app.concavepoints_meanLabel = uilabel(app.givedataPanel);
            app.concavepoints_meanLabel.Text = 'concave points_mean';
            app.a8 = uieditfield(app.givedataPanel, 'numeric');
            app.a8.ValueChangedFcn = createCallbackFcn(app, @a8ValueChanged, true);
            app.symmetry_meanLabel = uilabel(app.givedataPanel);
            app.symmetry_meanLabel.Text = 'symmetry_mean';
            app.a9 = uieditfield(app.givedataPanel, 'numeric');
            app.a9.ValueChangedFcn = createCallbackFcn(app, @a9ValueChanged, true);
            app.fractal_dimension_meanLabel = uilabel(app.givedataPanel);
            app.fractal_dimension_meanLabel.Text = 'fractal_dimension_mean';
            app.a10 = uieditfield(app.givedataPanel, 'numeric');
            app.a10.ValueChangedFcn = createCallbackFcn(app, @a10ValueChanged, true);
            app.radius_seLabel = uilabel(app.givedataPanel);
            app.radius_seLabel.Text = 'radius_se';
            app.a11 = uieditfield(app.givedataPanel, 'numeric');
            app.a11.ValueChangedFcn = createCallbackFcn(app, @a11ValueChanged, true);
            app.texture_seLabel = uilabel(app.givedataPanel);
            app.texture_seLabel.Text = 'texture_se';
            app.a12 = uieditfield(app.givedataPanel, 'numeric');
            app.a12.ValueChangedFcn = createCallbackFcn(app, @a12ValueChanged, true);
            app.perimeter_seLabel = uilabel(app.givedataPanel);
            app.perimeter_seLabel.Text = 'perimeter_se';
            app.a13 = uieditfield(app.givedataPanel, 'numeric');
            app.a13.ValueChangedFcn = createCallbackFcn(app, @a13ValueChanged, true);
            app.area_seLabel = uilabel(app.givedataPanel);
            app.area_seLabel.Text = 'area_se';
            app.a14 = uieditfield(app.givedataPanel, 'numeric');
            app.a14.ValueChangedFcn = createCallbackFcn(app, @a14ValueChanged, true);
            app.smoothness_seLabel = uilabel(app.givedataPanel);
            app.smoothness_seLabel.Text = 'smoothness_se';
            app.a15 = uieditfield(app.givedataPanel, 'numeric');
            app.a15.ValueChangedFcn = createCallbackFcn(app, @a15ValueChanged, true);
            app.compactness_seLabel = uilabel(app.givedataPanel);
            app.compactness_seLabel.Text = 'compactness_se';
            app.a16 = uieditfield(app.givedataPanel, 'numeric');
            app.a16.ValueChangedFcn = createCallbackFcn(app, @a16ValueChanged, true);
            app.concavity_seLabel = uilabel(app.givedataPanel);
            app.concavity_seLabel.Text = 'concavity_se';
            app.a17 = uieditfield(app.givedataPanel, 'numeric');
            app.a17.ValueChangedFcn = createCallbackFcn(app, @a17ValueChanged, true);
            app.concavepoints_seLabel = uilabel(app.givedataPanel);
            app.concavepoints_seLabel.Text = 'concave points_se';
            app.a18 = uieditfield(app.givedataPanel, 'numeric');
            app.a18.ValueChangedFcn = createCallbackFcn(app, @a18ValueChanged, true);
            app.symmetry_seLabel = uilabel(app.givedataPanel);
            app.symmetry_seLabel.Text = 'symmetry_se';
            app.a19 = uieditfield(app.givedataPanel, 'numeric');
            app.a19.ValueChangedFcn = createCallbackFcn(app, @a19ValueChanged, true);
            app.fractal_dimension_seLabel = uilabel(app.givedataPanel);
            app.fractal_dimension_seLabel.Text = 'fractal_dimension_se';
            app.a20 = uieditfield(app.givedataPanel, 'numeric');
            app.a20.ValueChangedFcn = createCallbackFcn(app, @a20ValueChanged, true);
            app.givedataPanel_2 = uipanel(app.UIFigure);
            app.givedataPanel_2.Title = 'give data';
            app.a21Label = uilabel(app.givedataPanel_2);
            app.a21Label.Text = 'radius_worst';
            app.a21 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a21.ValueChangedFcn = createCallbackFcn(app, @a21ValueChanged, true);
            app.a22Label = uilabel(app.givedataPanel_2);
            app.a22Label.Text = 'texture_worst';
            app.a22 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a22.ValueChangedFcn = createCallbackFcn(app, @a22ValueChanged, true);
            app.a23Label = uilabel(app.givedataPanel_2);
            app.a23Label.Text = 'perimeter_worst';
            app.a23 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a23.ValueChangedFcn = createCallbackFcn(app, @a23ValueChanged, true);
            app.a24Label = uilabel(app.givedataPanel_2);
            app.a24Label.Text = 'area_worst';
            app.a24 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a24.ValueChangedFcn = createCallbackFcn(app, @a24ValueChanged, true);
            app.smoothness_worstLabel = uilabel(app.givedataPanel_2);
            app.smoothness_worstLabel.Text = 'smoothness_worst';
            app.a25 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a25.ValueChangedFcn = createCallbackFcn(app, @a25ValueChanged, true);
            app.compactness_worstLabel = uilabel(app.givedataPanel_2);
            app.compactness_worstLabel.Text = 'compactness_worst';
            app.a26 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a26.ValueChangedFcn = createCallbackFcn(app, @a26ValueChanged, true);
            app.concavity_worstLabel = uilabel(app.givedataPanel_2);
            app.concavity_worstLabel.Text = 'concavity_worst';
            app.a27 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a27.ValueChangedFcn = createCallbackFcn(app, @a27ValueChanged, true);
            app.concavepoints_worstLabel = uilabel(app.givedataPanel_2);
            app.concavepoints_worstLabel.Text = 'concave points_worst';
            app.a28 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a28.ValueChangedFcn = createCallbackFcn(app, @a28ValueChanged, true);
            app.symmetry_worstLabel = uilabel(app.givedataPanel_2);
            app.symmetry_worstLabel.Text = 'symmetry_worst';
            app.a29 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a29.ValueChangedFcn = createCallbackFcn(app, @a29ValueChanged, true);
            app.fractal_dimension_worstLabel = uilabel(app.givedataPanel_2);
            app.fractal_dimension_worstLabel.Text = 'fractal_dimension_worst';
            app.a30 = uieditfield(app.givedataPanel_2, 'numeric');
            app.a30.ValueChangedFcn = createCallbackFcn(app, @a30ValueChanged, true);
            app.CANCERPREDICTIONLabel = uilabel(app.UIFigure);
            app.CANCERPREDICTIONLabel.Text = 'CANCER PREDICTION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end